classdef mainCAO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulateurdebalanceUIFigure    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        UIAnim                         matlab.ui.control.UIAxes
        Lamp                           matlab.ui.control.Lamp
        ParamtresnumismatiquesLabel    matlab.ui.control.Label
        UICoinTable                    matlab.ui.control.Table
        UIMassAction                   matlab.ui.control.UIAxes
        SimulerButton                  matlab.ui.control.Button
        AddPanel                       matlab.ui.container.Panel
        AjouterButton                  matlab.ui.control.Button
        DeviseDropDown_2Label          matlab.ui.control.Label
        DeviseDropDown                 matlab.ui.control.DropDown
        MontantLabel                   matlab.ui.control.Label
        MontantDropDown                matlab.ui.control.DropDown
        QuantitEditField_2Label        matlab.ui.control.Label
        QuantitEditField               matlab.ui.control.NumericEditField
        AnneEditFieldLabel             matlab.ui.control.Label
        AnneEditField                  matlab.ui.control.NumericEditField
        RemPanel                       matlab.ui.container.Panel
        RetirerButton                  matlab.ui.control.Button
        LigneDropDownLabel             matlab.ui.control.Label
        LigneDropDown                  matlab.ui.control.DropDown
        LogLabel                       matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
        APIAddPanel                    matlab.ui.container.Panel
        APIAjouterButton               matlab.ui.control.Button
        APIEditField_1                 matlab.ui.control.NumericEditField
        DotLabel                       matlab.ui.control.Label
        APIEditField_2                 matlab.ui.control.NumericEditField
        DotLabel_2                     matlab.ui.control.Label
        APIEditField_3                 matlab.ui.control.NumericEditField
        IdentifiantAPILabel            matlab.ui.control.Label
        ManualCheckBox                 matlab.ui.control.CheckBox
        APICheckBox                    matlab.ui.control.CheckBox
        ExcelLabel                     matlab.ui.control.Label
        APILabel                       matlab.ui.control.Label
        PourtrouverunepicedemonnaieLabel  matlab.ui.control.Label
        urlNumismatButton              matlab.ui.control.Button
        ConfigurationTab               matlab.ui.container.Tab
        DimensionsdelabalancePanel     matlab.ui.container.Panel
        LongueurcmEditFieldLabel       matlab.ui.control.Label
        LongueurBalanceField           matlab.ui.control.NumericEditField
        LargeurcmEditFieldLabel        matlab.ui.control.Label
        LargeurBalanceField            matlab.ui.control.NumericEditField
        HauteurcmEditFieldLabel        matlab.ui.control.Label
        HauteurBalanceField            matlab.ui.control.NumericEditField
        PositionduplateauSliderLabel   matlab.ui.control.Label
        PositionduplateauSlider        matlab.ui.control.Slider
        UIDimension                    matlab.ui.control.UIAxes
        LameflexiblePanel              matlab.ui.container.Panel
        paisseurmmLabel                matlab.ui.control.Label
        EpaisseurLameField             matlab.ui.control.NumericEditField
        MassegEditFieldLabel           matlab.ui.control.Label
        MasseLameField                 matlab.ui.control.NumericEditField
        UILameAxes                     matlab.ui.control.UIAxes
        LongueurcmLabel                matlab.ui.control.Label
        LongueurLameField              matlab.ui.control.NumericEditField
        LargeurcmLabel                 matlab.ui.control.Label
        LargeurLameField               matlab.ui.control.NumericEditField
        CapteurcapacitifPanel          matlab.ui.container.Panel
        LongueurcmEditField_3Label     matlab.ui.control.Label
        LongueurCaptField              matlab.ui.control.NumericEditField
        LargeurcmEditField_3Label      matlab.ui.control.Label
        LargeurCaptField               matlab.ui.control.NumericEditField
        MatriauxDropDownLabel          matlab.ui.control.Label
        MateriauxCaptDropDown          matlab.ui.control.DropDown
        UICaptAxes                     matlab.ui.control.UIAxes
        EspacementcmSliderLabel        matlab.ui.control.Label
        EspacementcmSlider             matlab.ui.control.Slider
        ArchivePanel                   matlab.ui.container.Panel
        HistoriquedesconfigurationsListBoxLabel  matlab.ui.control.Label
        HistoriquedesconfigurationsListBox  matlab.ui.control.ListBox
        ParcourirSaveButton            matlab.ui.control.Button
        SaveEditField                  matlab.ui.control.EditField
        SauvegarderlesrsultatsLabel    matlab.ui.control.Label
        AutreconfigurationLabel        matlab.ui.control.Label
        ParcourirLoadButton            matlab.ui.control.Button
        LoadEditField                  matlab.ui.control.EditField
        ContraintestemporellesPanel    matlab.ui.container.Panel
        NombredchantillonsLabel        matlab.ui.control.Label
        NombredchantillonsEditField    matlab.ui.control.NumericEditField
        DuredesimulationLabel          matlab.ui.control.Label
        TempsparchantillonmsEditFieldLabel  matlab.ui.control.Label
        TempsparchantillonmsEditField  matlab.ui.control.NumericEditField
        vnementsLabel                  matlab.ui.control.Label
        UIEventTable                   matlab.ui.control.Table
        ActionneurlineairePanel        matlab.ui.container.Panel
        NombredespiresSpinnerLabel     matlab.ui.control.Label
        NombredespiresSpinner          matlab.ui.control.Spinner
        RayoncmLabel                   matlab.ui.control.Label
        RayonField                     matlab.ui.control.NumericEditField
        UIActionAxes                   matlab.ui.control.UIAxes
    end

    
    %% PRIVATE PROPERTIES
    properties (Access = private)
        LameArray;                   % Array containing all points that make-up the platform (lame flexible)
        LameNumPoints;               % Number of points in the array
        APICoinMap = containers.Map; % Map containing all coins coming from the API
        saveArchivePath;             % Path to data file saved in current simulation
        captHeight = 0.4;
    end
    %% PUBLIC PROPERTIES
    properties (Access = public)
        minCADYear;                  % Minimum year recorded in the Excel file for Canadian coins
        maxCADYear;                  % Maximum year recorded in the Excel file for Canadian coins
        coinCADMap;                  % Map containing all coins coming from the Excel file
    end
    
    %% ENABLE/DISABLE UI FOR SIMULATION
    methods (Access = private)
        
        function disableUI(app)
            % Disable numismat table and configurations
            set(app.UICoinTable, 'ColumnEditable', false);
            set(app.AddPanel.Children, 'Enable', false);
            set(app.APIAddPanel.Children, 'Enable', false);
            set(app.RemPanel.Children, 'Enable', false);
            set(app.ManualCheckBox, 'Enable', false);
            set(app.APICheckBox, 'Enable', false);
            
            % Disable scale dimension features
            set(app.LongueurBalanceField, 'Enable', false);
            set(app.LargeurBalanceField, 'Enable', false);
            set(app.HauteurBalanceField, 'Enable', false);
            set(app.PositionduplateauSlider, 'Enable', false);
            
            % Disable lame dimension features
            set(app.EpaisseurLameField, 'Enable', false);
            set(app.MasseLameField, 'Enable', false);
            set(app.LongueurLameField, 'Enable', false);
            set(app.LargeurLameField, 'Enable', false);
            
            % Disable position detector dimension features
            set(app.LongueurCaptField, 'Enable', false);
            set(app.LargeurCaptField, 'Enable', false);
            set(app.MateriauxCaptDropDown, 'Enable', false);
            
            % Disable archive features
            set(app.HistoriquedesconfigurationsListBox, 'Enable', false);
            set(app.ParcourirSaveButton, 'Enable', false);
            set(app.SaveEditField, 'Enable', false);
            set(app.ParcourirLoadButton, 'Enable', false);
            set(app.LoadEditField, 'Enable', false);
            
            % Disable time features
            set(app.NombredchantillonsEditField, 'Enable', false);
            set(app.DuredesimulationLabel, 'Enable', false);
            set(app.TempsparchantillonmsEditField, 'Enable', false);
            set(app.UIEventTable, 'Enable', 'off');
            
            % Disable activator features
            set(app.NombredespiresSpinner, 'Enable', false);
            set(app.RayonField, 'Enable', false);
        end
        
        function enableUI(app)
            % Disable numismat table and configurations
            set(app.UICoinTable, 'ColumnEditable', true);
            set(app.AddPanel.Children, 'Enable', true);
            set(app.APIAddPanel.Children, 'Enable', true);
            set(app.RemPanel.Children, 'Enable', true);
            set(app.ManualCheckBox, 'Enable', true);
            set(app.APICheckBox, 'Enable', true);
            
            % Disable scale dimension features
            set(app.LongueurBalanceField, 'Enable', true);
            set(app.LargeurBalanceField, 'Enable', true);
            set(app.HauteurBalanceField, 'Enable', true);
            set(app.PositionduplateauSlider, 'Enable', true);
            
            % Disable lame dimension features
            set(app.EpaisseurLameField, 'Enable', true);
            set(app.MasseLameField, 'Enable', true);
            set(app.LongueurLameField, 'Enable', true);
            set(app.LargeurLameField, 'Enable', true);
            
            % Disable position detector dimension features
            set(app.LongueurCaptField, 'Enable', true);
            set(app.LargeurCaptField, 'Enable', true);
            set(app.MateriauxCaptDropDown, 'Enable', true);
            
            % Disable archive features
            set(app.HistoriquedesconfigurationsListBox, 'Enable', true);
            set(app.ParcourirSaveButton, 'Enable', true);
            set(app.SaveEditField, 'Enable', true);
            set(app.ParcourirLoadButton, 'Enable', true);
            set(app.LoadEditField, 'Enable', true);
            
            % Disable time features
            set(app.NombredchantillonsEditField, 'Enable', true);
            set(app.TempsparchantillonmsEditField, 'Enable', true);
            set(app.UIEventTable, 'Enable', 'on');
            
            % Disable activator features
            set(app.NombredespiresSpinner, 'Enable', true);
            set(app.RayonField, 'Enable', true);
        end
    end
    
    %% MANAGE NUMISMAT CONFIGURATIONS
    methods (Access = private)

        function addToLigneDropDown(app)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col+1);
            
            for i=1:col
                cell_array(1,i) = curr_dropdown(1,i);
            end
            cell_array(1,end) = {int2str(col+1)};

            % Add new line to dropdown
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function remFromLigneDropDown(app)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col-1);
            
            for i=1:col
                if cell2mat(curr_dropdown(1,i)) ~= cell2mat(curr_dropdown(1,end))
                    cell_array(1,i) = curr_dropdown(1,i);
                end
            end

            % Add new line to dropdown
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function addToCoinTable(app,currency,amount,quantity,year,weight)
            % Validate if quantity provided is valid
            if quantity <= 0
                writeLogError(app,"Quantité de pièces invalide");
                return
            else
                writeNothing(app);
            end
            
            % Validate if year specified in query is valid
            if isinteger(year)
                if year < app.minCADYear
                    writeLogError(app,"Année invalide (minimum = " + int2str(app.minCADYear) + ")");
                    return
                elseif year > app.maxCADYear
                    writeLogError(app,"Année invalide (maximum = " + int2str(app.maxCADYear) + ")");
                    return
                end
            end
            
            if weight == 0
                writeLogWarning(app,"Pièce non disponible (ou non existante dans la base de données)");
                return
            end

            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            cell_array = cell(row+1,5);
            
            for i=1:size(curr_data,1)
                cell_array(i,:) = curr_data(i,:);
            end
            cell_array(end,:) = {currency, amount, quantity, year, weight};
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            addToLigneDropDown(app);
        end
        
        function remFromCoinTable(app,line)
            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            if row == 0
                writeLogError(app,"Aucun élément dans la table");
                return
            end
            
            cell_array = cell(row-1,5);
            index = line-'0';
            
            for i=1:size(curr_data,1)
                if i < size(curr_data,1)
                   if i >= index
                       cell_array(i,:) = curr_data(i+1,:);
                   else
                       cell_array(i,:) = curr_data(i,:);
                   end
                end
            end
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            remFromLigneDropDown(app);
        end
    end
    
    %% MANAGE DIMENSIONS CONFIGURATIONS
    methods (Access = private)
        
        function n_position = adjustSliderPosition(app,value)
            n_position = value/200+0.075;
        end
        
        function initUIValues(app)
            % Configurations for nimusmats
            set(app.ManualCheckBox,'Value',true);
            set(app.APIAddPanel.Children,'Enable',false);
            
            % Configurations for scale dimensions
            set(app.LongueurBalanceField,'Value',30);
            set(app.LargeurBalanceField,'Value',9);
            set(app.HauteurBalanceField,'Value',17);
            set(app.PositionduplateauSlider,'Limits',[3,22]);
            set(app.PositionduplateauSlider,'Value',(22-3)/2);
            
            % Configurations for lame dimensions
            set(app.EpaisseurLameField,'Value',2);
            set(app.LongueurLameField,'Value',app.LongueurBalanceField.Value);
            set(app.LargeurLameField,'Value',7);
            set(app.MasseLameField,'Value',10);
            
            % Configurations for detector dimensions
            set(app.LongueurCaptField,'Value',8);
            set(app.LargeurCaptField,'Value',7);
            set(app.EspacementcmSlider,'Value',3);
            
            % Configurations for activator dimensions
            set(app.NombredespiresSpinner,'Value',60);
            set(app.RayonField,'Value',2);

            % Initialize an empty array with said number of points
            app.LameArray = zeros(1,(app.LongueurBalanceField.Value-1)*10+1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add untracked paths
            addpath('plotFunc');
            addpath('logFunc');
            addpath('UIs');
            
            % Setup data from small database (future updates might include link to ANS API)
            setup();
            initUIValues(app);
            
            % Initially plot the base of the scale
            plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
            close all % Close remaining figures that pop-up for no reason
        end

        % Button pushed function: SimulerButton
        function SimulerButtonPushed(app, event)
            if app.SimulerButton.Text == "Stop"
                app.Lamp.Color = 'green';
                app.SimulerButton.Text = "Simuler";
                enableUI(app);
            elseif app.SimulerButton.Text == "Simuler"
                % Change UI assets for simulation
                app.Lamp.Color = 'red';
                app.SimulerButton.Text = "Stop";
                
%                 getConfigurations();
                
                % Disable different components in UI during runtime
                disableUI(app);
                                
                % Simulate with current configurations
%                 [ret, msg] = simuation(app,dimPanelData,lamePanelData,captPanelData,ampPanelData,actionPanelData);
%                 if ret ~= 1
%                     writeLogError(app,msg);
%                 end
            end
        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            switch (app.MontantDropDown.Value)
                case '1¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x1cent;
                case '5¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x5cent;
                case '10¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x10cent;
                case '20¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x20cent;
                case '25¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x25cent;
                case '50¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x50cent;
                case '1$'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x1dol;
                case '2$'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x2dol;
            end
            addToCoinTable(app,app.DeviseDropDown.Value,app.MontantDropDown.Value,app.QuantitEditField.Value,app.AnneEditField.Value,weight);
        end

        % Button pushed function: RetirerButton
        function RetirerButtonPushed(app, event)
            remFromCoinTable(app,app.LigneDropDown.Value);
        end

        % Value changed function: LongueurBalanceField
        function LongueurBalanceFieldValueChanged(app, event)
            value = app.LongueurBalanceField.Value;
            if value <= 0
                writeLogError(app,"Longueur de balance invalide (plus grande que 0)");
                return
            end
            
            % Set new limits for position slider
            set(app.PositionduplateauSlider,'Limits',[0.1*app.HauteurBalanceField.Value,...
                app.LongueurBalanceField.Value-app.LongueurCaptField.Value]);
            
            % Set new values in plots
            plotBase(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
            close all % Close remaining figures that pop-up for no reason
        end

        % Value changed function: LargeurBalanceField
        function LargeurBalanceFieldValueChanged(app, event)
            value = app.LargeurBalanceField.Value;
            if value <= 0
                writeLogError(app,"Largeur de balance invalide (plus grande que 0)");
                return
            end
        end

        % Value changed function: HauteurBalanceField
        function HauteurBalanceFieldValueChanged(app, event)
            value = app.HauteurBalanceField.Value;
            if value < 0
                writeLogError(app,"Hauteur de balance invalide (plus grande que 0)");
                return
            end
            
            % Set new limits for position slider
            set(app.PositionduplateauSlider,'Limits',[0.1*app.HauteurBalanceField.Value,...
                app.LongueurBalanceField.Value-app.LongueurCaptField.Value]);
            
            % Set new values in plots
            plotBase(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
            close all % Close remaining figures that pop-up for no reason
        end

        % Value changing function: PositionduplateauSlider
        function PositionduplateauSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue < 0
                writeLogError(app,"Position de l'actionneur (et du plateau) invalide");
                return
            end
            
            % Set new values in plots
            plotBase(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,changingValue),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
            close all % Close remaining figures that pop-up for no reason
        end

        % Button pushed function: ParcourirSaveButton
        function ParcourirSaveButtonPushed(app, event)
            % Browse computer directory
            directory = uigetdir();
            
            % Cancel has been pressed
            if directory == 0
                return
            end
                
            % Save directory on UI and in back-end
            app.SaveEditField.Value = directory;
            app.saveArchivePath = directory;
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            value = app.ManualCheckBox.Value;
            if value == true
                % Set values for API interaction to false
                set(app.APICheckBox,'Value',false);
                set(app.APIAddPanel.Children,'Enable',false);
                set(app.AddPanel.Children,'Enable',true);
                
                % Disable the Excel checkbox
                set(app.ManualCheckBox, 'Enable', false);
            end
        end

        % Value changed function: APICheckBox
        function APICheckBoxValueChanged(app, event)
            value = app.APICheckBox.Value;
            if value == true
                % Set values for Excel file interaction to false
                set(app.ManualCheckBox,'Value',false);
                set(app.APIAddPanel.Children,'Enable',true);
                set(app.AddPanel.Children,'Enable',false);
                
                % Disable the Excel checkbox
                set(app.APICheckBox, 'Enable', false);
            end
        end

        % Button pushed function: APIAjouterButton
        function APIAjouterButtonPushed(app, event)
            % Get API identifier from query in UI
            identifier = app.APIEditField_1.Value + "." + app.APIEditField_2.Value + "." + app.APIEditField_3.Value;
            
            % Send query to API and return data corresponding with query
            [title,region,weight,year] = getCoinById(app,identifier);
                
            % If none of the fields have been updated to a valid value
            if (title == "") && (region == "") && (weight == 0) && (year == "")
                writeLogError(app,"Identifiant: "+identifier+" introuvable dans la base de données")
                return
            end
            
            % Add response to API coin map
            app.APICoinMap(identifier) = struct('title',title,'region',region,'weight',weight,'year',year);
            
            % Add to coin table
            addToCoinTable(app,char(region),'Inconnu',1,char(year),weight);
        end

        % Button pushed function: urlNumismatButton
        function urlNumismatButtonPushed(app, event)
            % Open the database website
            dos('explorer http://numismatics.org/search/results');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SimulationTab
                plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
                close all
            elseif selectedTab == app.ConfigurationTab
                plotBase(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,app.EspacementcmSlider.Value);
                close all
            end
        end

        % Value changing function: EspacementcmSlider
        function EspacementcmSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue <= 0
                writeLogError(app,"Espacement du capteur invalide invalide");
                return
            end
            
            plotCapt(app.UICaptAxes,app.LongueurCaptField.Value,app.captHeight,app.LargeurCaptField.Value,changingValue);
            
            % Update animation on configuration side
            plotBase(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    adjustSliderPosition(app,app.PositionduplateauSlider.Value),app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value,app.EpaisseurLameField.Value, ...
                    app.LongueurCaptField.Value,changingValue);
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulateurdebalanceUIFigure = uifigure('Visible', 'off');
            app.SimulateurdebalanceUIFigure.Name = 'Simulateur de balance';
            app.TabGroup = uitabgroup(app.SimulateurdebalanceUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAnim = uiaxes(app.SimulationTab);
            title(app.UIAnim, '')
            xlabel(app.UIAnim, '')
            ylabel(app.UIAnim, '')
            app.UIAnim.XTick = [];
            app.UIAnim.XTickLabel = '';
            app.UIAnim.YTick = [];
            app.Lamp = uilamp(app.SimulationTab);
            app.ParamtresnumismatiquesLabel = uilabel(app.SimulationTab);
            app.ParamtresnumismatiquesLabel.Text = 'Paramètres numismatiques';
            app.UICoinTable = uitable(app.SimulationTab);
            app.UICoinTable.ColumnName = {'Devise'; 'Montant'; 'Quantité'; 'Année'; 'Poids (g)'};
            app.UICoinTable.RowName = {};
            app.UICoinTable.ColumnEditable = false;
            app.UIMassAction = uiaxes(app.SimulationTab);
            title(app.UIMassAction, 'Affichage de la masse')
            xlabel(app.UIMassAction, 'X')
            ylabel(app.UIMassAction, 'Y')
            app.SimulerButton = uibutton(app.SimulationTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.AddPanel = uipanel(app.SimulationTab);
            app.AjouterButton = uibutton(app.AddPanel, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Text = 'Ajouter';
            app.DeviseDropDown_2Label = uilabel(app.AddPanel);
            app.DeviseDropDown_2Label.Text = 'Devise';
            app.DeviseDropDown = uidropdown(app.AddPanel);
            app.DeviseDropDown.Items = {'CAD', 'USD'};
            app.DeviseDropDown.Value = 'CAD';
            app.MontantLabel = uilabel(app.AddPanel);
            app.MontantLabel.Text = 'Montant';
            app.MontantDropDown = uidropdown(app.AddPanel);
            app.MontantDropDown.Items = {'1¢', '5¢', '10¢', '20¢', '25¢', '50¢', '1$', '2$'};
            app.MontantDropDown.Value = '1¢';
            app.QuantitEditField_2Label = uilabel(app.AddPanel);
            app.QuantitEditField_2Label.Text = 'Quantité';
            app.QuantitEditField = uieditfield(app.AddPanel, 'numeric');
            app.AnneEditFieldLabel = uilabel(app.AddPanel);
            app.AnneEditFieldLabel.Text = 'Année';
            app.AnneEditField = uieditfield(app.AddPanel, 'numeric');
            app.RemPanel = uipanel(app.SimulationTab);
            app.RetirerButton = uibutton(app.RemPanel, 'push');
            app.RetirerButton.ButtonPushedFcn = createCallbackFcn(app, @RetirerButtonPushed, true);
            app.RetirerButton.Text = 'Retirer';
            app.LigneDropDownLabel = uilabel(app.RemPanel);
            app.LigneDropDownLabel.Text = 'Ligne:';
            app.LigneDropDown = uidropdown(app.RemPanel);
            app.LigneDropDown.Items = {};
            app.LigneDropDown.Value = {};
            app.LogLabel = uilabel(app.SimulationTab);
            app.LogLabel.Text = 'Log:';
            app.LogTextArea = uitextarea(app.SimulationTab);
            app.LogTextArea.Editable = 'off';
            app.APIAddPanel = uipanel(app.SimulationTab);
            app.APIAjouterButton = uibutton(app.APIAddPanel, 'push');
            app.APIAjouterButton.ButtonPushedFcn = createCallbackFcn(app, @APIAjouterButtonPushed, true);
            app.APIAjouterButton.Text = 'Ajouter';
            app.APIEditField_1 = uieditfield(app.APIAddPanel, 'numeric');
            app.DotLabel = uilabel(app.APIAddPanel);
            app.DotLabel.Text = '.';
            app.APIEditField_2 = uieditfield(app.APIAddPanel, 'numeric');
            app.DotLabel_2 = uilabel(app.APIAddPanel);
            app.DotLabel_2.Text = '.';
            app.APIEditField_3 = uieditfield(app.APIAddPanel, 'numeric');
            app.IdentifiantAPILabel = uilabel(app.APIAddPanel);
            app.IdentifiantAPILabel.Text = 'Identifiant (API)*';
            app.ManualCheckBox = uicheckbox(app.SimulationTab);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = '';
            app.APICheckBox = uicheckbox(app.SimulationTab);
            app.APICheckBox.ValueChangedFcn = createCallbackFcn(app, @APICheckBoxValueChanged, true);
            app.APICheckBox.Text = '';
            app.ExcelLabel = uilabel(app.SimulationTab);
            app.ExcelLabel.Text = 'Excel';
            app.APILabel = uilabel(app.SimulationTab);
            app.APILabel.Text = 'API';
            app.PourtrouverunepicedemonnaieLabel = uilabel(app.SimulationTab);
            app.PourtrouverunepicedemonnaieLabel.Text = '* Pour trouver une pièce de monnaie:';
            app.urlNumismatButton = uibutton(app.SimulationTab, 'push');
            app.urlNumismatButton.ButtonPushedFcn = createCallbackFcn(app, @urlNumismatButtonPushed, true);
            app.urlNumismatButton.Text = 'http://numismatics.org/search/results';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DimensionsdelabalancePanel = uipanel(app.ConfigurationTab);
            app.DimensionsdelabalancePanel.Title = 'Dimensions de la balance';
            app.LongueurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LongueurcmEditFieldLabel.Text = 'Longueur (cm)';
            app.LongueurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LongueurBalanceField.ValueChangedFcn = createCallbackFcn(app, @LongueurBalanceFieldValueChanged, true);
            app.LargeurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LargeurcmEditFieldLabel.Text = 'Largeur (cm)';
            app.LargeurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LargeurBalanceField.ValueChangedFcn = createCallbackFcn(app, @LargeurBalanceFieldValueChanged, true);
            app.HauteurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.HauteurcmEditFieldLabel.Text = 'Hauteur (cm)';
            app.HauteurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.HauteurBalanceField.ValueChangedFcn = createCallbackFcn(app, @HauteurBalanceFieldValueChanged, true);
            app.PositionduplateauSliderLabel = uilabel(app.DimensionsdelabalancePanel);
            app.PositionduplateauSliderLabel.Text = 'Position du plateau';
            app.PositionduplateauSlider = uislider(app.DimensionsdelabalancePanel);
            app.PositionduplateauSlider.ValueChangingFcn = createCallbackFcn(app, @PositionduplateauSliderValueChanging, true);
            app.UIDimension = uiaxes(app.DimensionsdelabalancePanel);
            title(app.UIDimension, '')
            xlabel(app.UIDimension, 'X (cm)')
            ylabel(app.UIDimension, 'Y (cm)')
            app.LameflexiblePanel = uipanel(app.ConfigurationTab);
            app.LameflexiblePanel.Title = 'Lame flexible';
            app.paisseurmmLabel = uilabel(app.LameflexiblePanel);
            app.paisseurmmLabel.Text = 'Épaisseur (mm)';
            app.EpaisseurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.MassegEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.MassegEditFieldLabel.Text = 'Masse (g)';
            app.MasseLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.UILameAxes = uiaxes(app.LameflexiblePanel);
            title(app.UILameAxes, 'Dimensions de la lame')
            xlabel(app.UILameAxes, 'X')
            ylabel(app.UILameAxes, 'Y')
            app.UILameAxes.ZTickLabel = {'0'; '0.5'; '1'};
            app.LongueurcmLabel = uilabel(app.LameflexiblePanel);
            app.LongueurcmLabel.Text = 'Longueur (cm)';
            app.LongueurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.LargeurcmLabel = uilabel(app.LameflexiblePanel);
            app.LargeurcmLabel.Text = 'Largeur (cm)';
            app.LargeurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.CapteurcapacitifPanel = uipanel(app.ConfigurationTab);
            app.CapteurcapacitifPanel.Title = 'Capteur capacitif';
            app.LongueurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LongueurcmEditField_3Label.Text = 'Longueur (cm)';
            app.LongueurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.LargeurcmEditField_3Label = uilabel(app.CapteurcapacitifPanel);
            app.LargeurcmEditField_3Label.Text = 'Largeur (cm)';
            app.LargeurCaptField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.MatriauxDropDownLabel = uilabel(app.CapteurcapacitifPanel);
            app.MatriauxDropDownLabel.Text = 'Matériaux';
            app.MateriauxCaptDropDown = uidropdown(app.CapteurcapacitifPanel);
            app.MateriauxCaptDropDown.Items = {'Fer', 'Aliage..'};
            app.MateriauxCaptDropDown.Value = 'Fer';
            app.UICaptAxes = uiaxes(app.CapteurcapacitifPanel);
            title(app.UICaptAxes, 'Dimensions du capteur')
            xlabel(app.UICaptAxes, 'X')
            ylabel(app.UICaptAxes, 'Y')
            app.UICaptAxes.ZTickLabel = {'0'; '0.5'; '1'};
            app.EspacementcmSliderLabel = uilabel(app.CapteurcapacitifPanel);
            app.EspacementcmSliderLabel.Text = {'Espacement'; '(cm)'};
            app.EspacementcmSlider = uislider(app.CapteurcapacitifPanel);
            app.EspacementcmSlider.Limits = [0.5 4];
            app.EspacementcmSlider.ValueChangingFcn = createCallbackFcn(app, @EspacementcmSliderValueChanging, true);
            app.EspacementcmSlider.Value = 0.5;
            app.ArchivePanel = uipanel(app.ConfigurationTab);
            app.ArchivePanel.Title = 'Archive';
            app.HistoriquedesconfigurationsListBoxLabel = uilabel(app.ArchivePanel);
            app.HistoriquedesconfigurationsListBoxLabel.Text = 'Historique des configurations';
            app.HistoriquedesconfigurationsListBox = uilistbox(app.ArchivePanel);
            app.HistoriquedesconfigurationsListBox.Items = {};
            app.HistoriquedesconfigurationsListBox.Value = {};
            app.ParcourirSaveButton = uibutton(app.ArchivePanel, 'push');
            app.ParcourirSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ParcourirSaveButtonPushed, true);
            app.ParcourirSaveButton.Text = 'Parcourir...';
            app.SaveEditField = uieditfield(app.ArchivePanel, 'text');
            app.SaveEditField.Editable = 'off';
            app.SaveEditField.Value = '<Répertoire des résultats>';
            app.SauvegarderlesrsultatsLabel = uilabel(app.ArchivePanel);
            app.SauvegarderlesrsultatsLabel.Text = 'Sauvegarder les résultats';
            app.AutreconfigurationLabel = uilabel(app.ArchivePanel);
            app.AutreconfigurationLabel.Text = 'Autre configuration';
            app.ParcourirLoadButton = uibutton(app.ArchivePanel, 'push');
            app.ParcourirLoadButton.Text = 'Parcourir...';
            app.LoadEditField = uieditfield(app.ArchivePanel, 'text');
            app.LoadEditField.Editable = 'off';
            app.LoadEditField.Value = '<Répertoire des résultats>';
            app.ContraintestemporellesPanel = uipanel(app.ConfigurationTab);
            app.ContraintestemporellesPanel.Title = 'Contraintes temporelles';
            app.NombredchantillonsLabel = uilabel(app.ContraintestemporellesPanel);
            app.NombredchantillonsLabel.Text = {'Nombre '; 'd''échantillons'};
            app.NombredchantillonsEditField = uieditfield(app.ContraintestemporellesPanel, 'numeric');
            app.DuredesimulationLabel = uilabel(app.ContraintestemporellesPanel);
            app.DuredesimulationLabel.Text = 'Durée de simulation';
            app.TempsparchantillonmsEditFieldLabel = uilabel(app.ContraintestemporellesPanel);
            app.TempsparchantillonmsEditFieldLabel.Text = {'Temps par '; 'échantillon (ms)'};
            app.TempsparchantillonmsEditField = uieditfield(app.ContraintestemporellesPanel, 'numeric');
            app.vnementsLabel = uilabel(app.ContraintestemporellesPanel);
            app.vnementsLabel.Text = 'Évènements';
            app.UIEventTable = uitable(app.ContraintestemporellesPanel);
            app.UIEventTable.ColumnName = {'Temps (ms)'; 'Action'; 'Paramètre'};
            app.UIEventTable.RowName = {};
            app.ActionneurlineairePanel = uipanel(app.ConfigurationTab);
            app.ActionneurlineairePanel.Title = 'Actionneur linéaire';
            app.NombredespiresSpinnerLabel = uilabel(app.ActionneurlineairePanel);
            app.NombredespiresSpinnerLabel.Text = 'Nombre de spires';
            app.NombredespiresSpinner = uispinner(app.ActionneurlineairePanel);
            app.RayoncmLabel = uilabel(app.ActionneurlineairePanel);
            app.RayoncmLabel.Text = 'Rayon (cm)';
            app.RayonField = uieditfield(app.ActionneurlineairePanel, 'numeric');
            app.UIActionAxes = uiaxes(app.ActionneurlineairePanel);
            title(app.UIActionAxes, 'Dimensions de l''actionneur')
            xlabel(app.UIActionAxes, 'X')
            ylabel(app.UIActionAxes, 'Y')
            app.UIActionAxes.ZTickLabel = {'0'; '0.5'; '1'};
            % Show the figure after all components are created
            app.SimulateurdebalanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainCAO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulateurdebalanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulateurdebalanceUIFigure)
        end
    end
end